classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Label             matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Label_3           matlab.ui.control.Label
        Label_4           matlab.ui.control.Label
        Label_6           matlab.ui.control.Label
        Label_7           matlab.ui.control.Label
        Label_5           matlab.ui.control.Label
        Label_9           matlab.ui.control.Label
        EditField         matlab.ui.control.EditField
        EditField_8Label  matlab.ui.control.Label
        EditField_8       matlab.ui.control.EditField
        EditField_9Label  matlab.ui.control.Label
        EditField_9       matlab.ui.control.EditField
        EditField_2Label  matlab.ui.control.Label
        EditField_2       matlab.ui.control.EditField
        EditField_3Label  matlab.ui.control.Label
        EditField_3       matlab.ui.control.EditField
        EditField_4Label  matlab.ui.control.Label
        EditField_4       matlab.ui.control.EditField
        EditField_5Label  matlab.ui.control.Label
        EditField_5       matlab.ui.control.NumericEditField
        EditField_6Label  matlab.ui.control.Label
        EditField_6       matlab.ui.control.NumericEditField
        EditField_7Label  matlab.ui.control.Label
        EditField_7       matlab.ui.control.NumericEditField
        Button            matlab.ui.control.Button
        Label_10          matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed2(app, event)
            if app.EditField_5.Value + app.EditField_6.Value + app.EditField_7.Value > 250
                app.Label_6.Visible = true;
            else
                app.Label_7.Visible = true;
            end
            app.Label_10.Text = num2str(app.EditField_5.Value + app.EditField_6.Value + app.EditField_7.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Фамилия, имя, отчество';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Предмет';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Баллы ЕГЭ';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Сумма баллов';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Visible = 'off';
            app.Label_6.Text = 'Поздравляем!';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Visible = 'off';
            app.Label_7.Text = 'Увы, вы не прошли';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Проходной балл: 250';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_8Label = uilabel(app.UIFigure);
            app.EditField_8Label.Text = '';
            app.EditField_8 = uieditfield(app.UIFigure, 'text');
            app.EditField_9Label = uilabel(app.UIFigure);
            app.EditField_9Label.Text = '';
            app.EditField_9 = uieditfield(app.UIFigure, 'text');
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = '';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_4Label = uilabel(app.UIFigure);
            app.EditField_4Label.Text = '';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_5Label = uilabel(app.UIFigure);
            app.EditField_5Label.Text = '';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6Label = uilabel(app.UIFigure);
            app.EditField_6Label.Text = '';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7Label = uilabel(app.UIFigure);
            app.EditField_7Label.Text = '';
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.Button.Text = 'Посчитать';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end